classdef flagGNSSOffsets < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        AuthorEditField              matlab.ui.control.EditField
        AuthorEditFieldLabel         matlab.ui.control.Label
        InputFileEditField           matlab.ui.control.EditField
        InputFileLabel               matlab.ui.control.Label
        ChooseStationListFileButton  matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        PlottingOptionsLabel         matlab.ui.control.Label
        ManualOffsetFlaggingLabel    matlab.ui.control.Label
        CorrectManualButton          matlab.ui.control.Button
        CorrectNGLButton             matlab.ui.control.Button
        ClearStationOffsetsButton    matlab.ui.control.Button
        SetOutputDirectoryButton     matlab.ui.control.Button
        OutputFileEditField          matlab.ui.control.EditField
        OutputFileEditFieldLabel     matlab.ui.control.Label
        LogButton                    matlab.ui.control.Button
        SeparateButton               matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        DetrendButton                matlab.ui.control.Button
        ZeroButton                   matlab.ui.control.Button
        PanButton                    matlab.ui.control.Button
        ZoomButton                   matlab.ui.control.Button
        ValidIDLamp                  matlab.ui.control.Lamp
        StationIDEditField           matlab.ui.control.EditField
        StationIDEditFieldLabel      matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        hTimeseries= [];
        hUNREquip= [];
        hUNREarthquake= [];
        hManual= [];
        hCurrentLog= [];
        StationID= []; 
        Date= datetime(2000,1,1);
        Displacement= nan;
        Station= flag.downloadUNRStations;
        UNRStep= flag.downloadUNRSteps;
        StationSteps= [];
        Directory= pwd;
        Filename= fullfile(pwd,'manualOffsets.txt');
        AllManualSteps= table;
        logging= false;
        offsetDate= NaT;
    end
    
    methods (Access = private)
        
        function loadTimeseries(app)
            [app.Date,app.Displacement]= flag.downloadUNRTimeseries(app.StationID);
            
            I= strcmpi(app.UNRStep.ID,app.StationID);
            
            app.StationSteps= app.UNRStep(I,:);
            
            app.updatePlot
        end
        
        function updatePlot(app)
            for i= 1:3
                app.hTimeseries(i).XData= app.Date;
                app.hTimeseries(i).YData= app.Displacement(:,i);
            end
            app.hUNREquip.XData= NaT;
            app.hUNREquip.YData= NaN;
            app.hUNREarthquake.XData= NaT;
            app.hUNREarthquake.YData= NaN;
            app.hManual.XData= NaT;
            app.hManual.YData= NaN;
            app.hCurrentLog.XData= NaT;
            app.hCurrentLog.YData= NaN;
            ylim(app.UIAxes,'auto')
            
            YLIM= ylim(app.UIAxes);
            YLIM= mean(YLIM)+ [-1 1.6]*.5*diff(YLIM);
            ylim(app.UIAxes,YLIM)
            
            equipStepDates= unique(app.StationSteps.Date(app.StationSteps.Type == 1));
            if ~isempty(equipStepDates)
                X= [equipStepDates equipStepDates NaT(size(equipStepDates))]';
                Y= repmat([YLIM nan],length(equipStepDates),1)';
                
                app.hUNREquip.XData= X(:);
                app.hUNREquip.YData= Y(:);
            end
            
            earthquakeStepDates= unique(app.StationSteps.Date(app.StationSteps.Type == 2));
            if ~isempty(earthquakeStepDates)
                X= [earthquakeStepDates earthquakeStepDates NaT(size(earthquakeStepDates))]';
                Y= repmat([YLIM nan],length(earthquakeStepDates),1)';
                
                app.hUNREarthquake.XData= X(:);
                app.hUNREarthquake.YData= Y(:);
            end
            
            if ~isempty(app.AllManualSteps)
                I= app.AllManualSteps.Type == 3 & strcmpi(app.AllManualSteps.ID,app.StationID);
                manualStepDates= unique(app.AllManualSteps.Date(I));
                if ~isempty(manualStepDates)
                    X= [manualStepDates manualStepDates NaT(size(manualStepDates))]';
                    Y= repmat([YLIM nan],length(manualStepDates),1)';

                    app.hManual.XData= X(:);
                    app.hManual.YData= Y(:);
                end
            end
            
            app.updateLogPlot
        end
        
        function resetXLim(app)
            xlim(app.UIAxes,app.Date([1 end]))
            app.updatePlot
        end
        
        function updateLogPlot(app)
            if isnat(app.offsetDate)
                app.hCurrentLog.XData= NaT;
                app.hCurrentLog.YData= nan;
            else
                app.hCurrentLog.XData= app.offsetDate+ [0 0];
                app.hCurrentLog.YData= app.UIAxes.YLim;
            end
        end
        
        function ButtonDownCallback(app,~,~)
            app.log
            app.stopLogging
        end
        
        function log(app)
            flag.appendManualOffset(app.Filename,app.StationID,app.offsetDate,app.AuthorEditField.Value);
            app.AllManualSteps= flag.loadOffsetFile(app.Filename);
            app.updatePlot
        end
        
        function stopLogging(app)
            app.logging= false;
            app.offsetDate= NaT;
            app.updateLogPlot
            zoom(app.UIAxes,'xon')
        end
        
        function setStation(app)
            app.StationIDEditField.Value= app.StationID;
            app.ValidIDLamp.Color= [0 1 0];
            app.ResetButtonPushed
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StationID= app.StationIDEditField.Value;
            
            app.OutputFileEditField.Value= app.Filename;
            
            app.AllManualSteps= flag.loadOffsetFile(app.Filename);
            
            app.hTimeseries= plot(app.UIAxes,NaT,nan,NaT,nan,NaT,nan);
            set(app.UIAxes,'FontSize',16,'FontName','Times')
            zoom(app.UIAxes,'xon')
            hold(app.UIAxes,'on')
            app.hUNREquip= plot(app.UIAxes,NaT,nan,'--','Color',[0 1 1],'LineWidth',2);
            app.hUNREarthquake= plot(app.UIAxes,NaT,nan,'--','Color',[.5 .5 .5],'LineWidth',2);
            app.hManual= plot(app.UIAxes,NaT,nan,'--','Color',[0 1 0],'LineWidth',2);
            app.hCurrentLog= plot(app.UIAxes,NaT,nan,'Color',[0 1 0],'LineWidth',2,...
                'ButtonDownFcn',@(src,event)app.ButtonDownCallback(src,event));
            hold(app.UIAxes,'off')
            legend(app.UIAxes,'East','North','Up','NGL Equipment Change',...
                'NGL Earthquake','Manually Logged Offsets','Current Log')

            
            if length(app.StationID) == 4
                if any(contains(app.Station.ID,app.StationID))
                    app.loadTimeseries();
                    
                    app.ValidIDLamp.Color= [0 1 0];
                else
                    app.ValidIDLamp.Color= [1 0 0];
                end
            end
        end

        % Value changed function: StationIDEditField
        function StationIDEditFieldValueChanged(app, event)
            value = app.StationIDEditField.Value;
            
            if length(value) == 4
                if any(contains(app.Station.ID,value))
                    app.StationID= value;
                
                    app.loadTimeseries();
                    app.resetXLim
                    
                    app.ValidIDLamp.Color= [0 1 0];
                else
                    app.ValidIDLamp.Color= [1 0 0];
                end
            end
        end

        % Button pushed function: PanButton
        function PanButtonPushed(app, event)
            pan(app.UIAxes,'xon')
        end

        % Button pushed function: ZoomButton
        function ZoomButtonPushed(app, event)
            zoom(app.UIAxes,'xon')
        end

        % Button pushed function: ZeroButton
        function ZeroButtonPushed(app, event)
            XLIM= app.UIAxes.XLim;
            
            [~,I]= min(abs(app.Date-XLIM(1)));
            
            app.Displacement= app.Displacement- app.Displacement(I,:);
            
            app.updatePlot
        end

        % Value changing function: StationIDEditField
        function StationIDEditFieldValueChanging(app, event)
            changingValue = event.Value;
            if length(changingValue) == 4 && any(contains(app.Station.ID,changingValue))
                app.ValidIDLamp.Color= [0 1 0];
            else
                app.ValidIDLamp.Color= [1 0 0];
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.loadTimeseries
            app.resetXLim
        end

        % Button pushed function: DetrendButton
        function DetrendButtonPushed(app, event)
            vel= diff(app.Displacement([1 end],:))./days(diff(app.Date([1 end])));
            
            app.Displacement= app.Displacement- vel.*days(app.Date- app.Date(1));
            
            app.ZeroButtonPushed
            
            app.updatePlot
            
            app.SeparateButtonPushed
            app.SeparateButtonPushed
        end

        % Button pushed function: SeparateButton
        function SeparateButtonPushed(app, event)
            app.Displacement= app.Displacement+ 20*[1 0 -1];
            
            app.updatePlot
        end

        % Button pushed function: SetOutputDirectoryButton
        function SetOutputDirectoryButtonPushed(app, event)
            dir= uigetdir();
            
            if ischar(dir)
                app.Directory= dir;
                app.Filename= fullfile(app.Directory,'manualOffsets.txt');
                app.OutputFileEditField.Value= app.Filename;
            end
            
            figure(app.UIFigure)
        end

        % Button pushed function: LogButton
        function LogButtonPushed(app, event)
            if ~app.logging
                zoom(app.UIAxes,'off')
                pan(app.UIAxes,'off')
                app.logging= true;
            else
                app.stopLogging
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if app.logging
                point= get(app.UIAxes,'CurrentPoint');
                datePosition= num2ruler(point(1),app.UIAxes.XAxis);
                
                app.offsetDate= datePosition;
                
                app.updateLogPlot
            end
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            
            switch key
                case 'escape'
                    app.stopLogging
            end
        end

        % Button pushed function: ClearStationOffsetsButton
        function ClearStationOffsetsButtonPushed(app, event)
            app.AllManualSteps(strcmpi(app.AllManualSteps.ID,app.StationID),:)= [];
            flag.overwriteManualOffsets(app.Filename,app.AllManualSteps.ID,app.AllManualSteps.Date,app.AllManualSteps.Information);
            app.AllManualSteps= flag.loadOffsetFile(app.Filename);
            app.updatePlot
        end

        % Button pushed function: CorrectNGLButton
        function CorrectNGLButtonPushed(app, event)
            for i= 1:height(app.StationSteps)
                app.Displacement= flag.correctOffset(app.Date,app.Displacement,app.StationSteps.Date(i));
            end
            app.updatePlot
        end

        % Button pushed function: CorrectManualButton
        function CorrectManualButtonPushed(app, event)
            for i= 1:height(app.AllManualSteps)
                if strcmpi(app.AllManualSteps.ID(i),app.StationID)
                    app.Displacement= flag.correctOffset(app.Date,app.Displacement,app.AllManualSteps.Date(i));
                end
            end
            app.updatePlot
        end

        % Button pushed function: Button
        function RightButtonPushed(app, event)
            i= find(strcmpi(app.Station.ID,app.StationID));
            inew= mod(i+1-1,height(app.Station))+1;
            app.StationID= app.Station.ID(inew);
            
            app.setStation
        end

        % Button pushed function: Button_2
        function LeftButtonPushed(app, event)
            i= find(strcmpi(app.Station.ID,app.StationID));
            inew= mod(i-1-1,height(app.Station))+1;
            app.StationID= app.Station.ID(inew);
            
            app.setStation
        end

        % Button pushed function: ChooseStationListFileButton
        function ChooseStationListFileButtonPushed(app, event)
            [file,location]= uigetfile('*.txt');
            
            if ~isequal(file,0)
                filename= fullfile(location,file);
                AllowedStations= readlines(filename);
                
                AllStations= flag.downloadUNRStations;
                
                [~,ia]= intersect(AllStations.ID,AllowedStations);
                
                app.Station= AllStations(ia,:);
                app.StationID= app.Station.ID(1);
                app.setStation
                
                app.InputFileEditField.Value= file;
                
                figure(app.UIFigure)
            else
                figure(app.UIFigure)
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {594, 594};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {210, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StationIDEditFieldLabel = uilabel(app.LeftPanel);
            app.StationIDEditFieldLabel.Text = 'Station ID';
            app.StationIDEditField = uieditfield(app.LeftPanel, 'text');
            app.StationIDEditField.CharacterLimits = [0 4];
            app.StationIDEditField.ValueChangedFcn = createCallbackFcn(app, @StationIDEditFieldValueChanged, true);
            app.StationIDEditField.ValueChangingFcn = createCallbackFcn(app, @StationIDEditFieldValueChanging, true);
            app.StationIDEditField.Value = 'ZYWI';
            app.ValidIDLamp = uilamp(app.LeftPanel);
            app.ZoomButton = uibutton(app.LeftPanel, 'push');
            app.ZoomButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomButtonPushed, true);
            app.ZoomButton.Text = 'Zoom';
            app.PanButton = uibutton(app.LeftPanel, 'push');
            app.PanButton.ButtonPushedFcn = createCallbackFcn(app, @PanButtonPushed, true);
            app.PanButton.Text = 'Pan';
            app.ZeroButton = uibutton(app.LeftPanel, 'push');
            app.ZeroButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroButtonPushed, true);
            app.ZeroButton.Text = 'Zero';
            app.DetrendButton = uibutton(app.LeftPanel, 'push');
            app.DetrendButton.ButtonPushedFcn = createCallbackFcn(app, @DetrendButtonPushed, true);
            app.DetrendButton.Text = 'Detrend';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SeparateButton = uibutton(app.LeftPanel, 'push');
            app.SeparateButton.ButtonPushedFcn = createCallbackFcn(app, @SeparateButtonPushed, true);
            app.SeparateButton.Text = 'Separate';
            app.LogButton = uibutton(app.LeftPanel, 'push');
            app.LogButton.ButtonPushedFcn = createCallbackFcn(app, @LogButtonPushed, true);
            app.LogButton.Text = 'Log';
            app.OutputFileEditFieldLabel = uilabel(app.LeftPanel);
            app.OutputFileEditFieldLabel.Text = 'Output File';
            app.OutputFileEditField = uieditfield(app.LeftPanel, 'text');
            app.OutputFileEditField.Editable = 'off';
            app.SetOutputDirectoryButton = uibutton(app.LeftPanel, 'push');
            app.SetOutputDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SetOutputDirectoryButtonPushed, true);
            app.SetOutputDirectoryButton.Text = 'Set Output Directory';
            app.ClearStationOffsetsButton = uibutton(app.LeftPanel, 'push');
            app.ClearStationOffsetsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearStationOffsetsButtonPushed, true);
            app.ClearStationOffsetsButton.Text = 'Clear Station Offsets';
            app.CorrectNGLButton = uibutton(app.LeftPanel, 'push');
            app.CorrectNGLButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectNGLButtonPushed, true);
            app.CorrectNGLButton.Text = 'Correct NGL';
            app.CorrectManualButton = uibutton(app.LeftPanel, 'push');
            app.CorrectManualButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectManualButtonPushed, true);
            app.CorrectManualButton.Text = {'Correct'; 'Manual'};
            app.ManualOffsetFlaggingLabel = uilabel(app.LeftPanel);
            app.ManualOffsetFlaggingLabel.Text = 'Manual Offset Flagging';
            app.PlottingOptionsLabel = uilabel(app.LeftPanel);
            app.PlottingOptionsLabel.Text = 'Plotting Options';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.Button.Text = '→';
            app.Button_2 = uibutton(app.LeftPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.Button_2.Text = '←';
            app.ChooseStationListFileButton = uibutton(app.LeftPanel, 'push');
            app.ChooseStationListFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseStationListFileButtonPushed, true);
            app.ChooseStationListFileButton.Text = 'Choose Station List File';
            app.InputFileLabel = uilabel(app.LeftPanel);
            app.InputFileLabel.Text = 'Input File';
            app.InputFileEditField = uieditfield(app.LeftPanel, 'text');
            app.InputFileEditField.Editable = 'off';
            app.AuthorEditFieldLabel = uilabel(app.LeftPanel);
            app.AuthorEditFieldLabel.Text = 'Author';
            app.AuthorEditField = uieditfield(app.LeftPanel, 'text');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            ylabel(app.UIAxes, 'Displacement (mm)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = flagGNSSOffsets

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end